classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Wave1Tab                    matlab.ui.container.Tab
        AzimuthEditField            matlab.ui.control.EditField
        AzimuthEditField_3Label_2   matlab.ui.control.Label
        ypositionEditField          matlab.ui.control.NumericEditField
        ypositionEditField_3Label   matlab.ui.control.Label
        xpositionEditField          matlab.ui.control.NumericEditField
        xpositionEditField_3Label   matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label   matlab.ui.control.Label
        WavelengthEditField         matlab.ui.control.NumericEditField
        WavelengthEditField_3Label  matlab.ui.control.Label
        WavetypeDropDown            matlab.ui.control.DropDown
        WavetypeDropDown_3Label     matlab.ui.control.Label
        Wave2Tab                    matlab.ui.container.Tab
        AzimuthEditField_2          matlab.ui.control.EditField
        AzimuthEditField_3Label_3   matlab.ui.control.Label
        ypositionEditField_2        matlab.ui.control.NumericEditField
        ypositionEditField_4Label   matlab.ui.control.Label
        xpositionEditField_2        matlab.ui.control.NumericEditField
        xpositionEditField_4Label   matlab.ui.control.Label
        AmplitudeEditField_2        matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label   matlab.ui.control.Label
        WavelengthEditField_2       matlab.ui.control.NumericEditField
        WavelengthEditField_2Label  matlab.ui.control.Label
        WavetypeDropDown_2          matlab.ui.control.DropDown
        WavetypeDropDown_2Label     matlab.ui.control.Label
        AnimateButton               matlab.ui.control.StateButton
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wave1;
        wave2;
        t0;
    end
    
    methods (Access = private)
        
        function refreshall(app, nwave)
            if nwave == 1
                app.wave1.refreshmesh();
            elseif nwave == 2
                app.wave2.refreshmesh();
            else
                error('nwave must be 1 or 2');
            end
            draw_waves(app.t0, linspace(-5, 5, 300), linspace(-5, 5, 300), app.wave1+app.wave2, 'real', app.UIAxes);
            draw_waves(app.t0, linspace(-5, 5, 300), linspace(-5, 5, 300), app.wave1+app.wave2, 'abs', app.UIAxes2);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.WavetypeDropDown.Value = "Gaussian";
            app.WavelengthEditField.Value = 1;
            app.AmplitudeEditField.Value = 1;
            app.AzimuthEditField.Value = "pi/4";
            app.xpositionEditField.Value = 0;
            app.ypositionEditField.Value = 0;
            
            app.WavetypeDropDown_2.Value = "Gaussian";
            app.WavelengthEditField_2.Value = 1;
            app.AmplitudeEditField_2.Value = 1;
            app.AzimuthEditField_2.Value = "-pi/4";
            app.xpositionEditField_2.Value = 0;
            app.ypositionEditField_2.Value = 0;
            
            app.wave1 = Wave(str2num(app.AzimuthEditField.Value), 30, app.WavelengthEditField.Value, app.WavetypeDropDown.Value);
            app.wave2 = Wave(str2num(app.AzimuthEditField_2.Value), 30, app.WavelengthEditField_2.Value, app.WavetypeDropDown_2.Value);
            app.t0 = 0;
            
            draw_waves(app.t0, linspace(-5, 5, 300), linspace(-5, 5, 300), app.wave1+app.wave2, 'real', app.UIAxes);
            draw_waves(app.t0, linspace(-5, 5, 300), linspace(-5, 5, 300), app.wave1+app.wave2, 'abs', app.UIAxes2);
        end

        % Value changed function: ypositionEditField
        function ypositionEditFieldValueChanged(app, event)
            value = app.ypositionEditField.Value;
            app.wave1.y0 = value;
            app.refreshall(1);
        end

        % Value changed function: xpositionEditField
        function xpositionEditFieldValueChanged(app, event)
            value = app.xpositionEditField.Value;
            app.wave1.x0 = value;
            app.refreshall(1);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.wave1.A = value;
            app.refreshall(1);
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            value = app.WavelengthEditField.Value;
            app.wave1.lambda = value;
            app.refreshall(1);
        end

        % Value changed function: WavetypeDropDown
        function WavetypeDropDownValueChanged(app, event)
            value = app.WavetypeDropDown.Value;
            app.wave1.type = value;
            app.refreshall(1);
        end

        % Value changed function: ypositionEditField_2
        function ypositionEditField_2ValueChanged(app, event)
            value = app.ypositionEditField_2.Value;
            app.wave2.y0 = value;
            app.refreshall(2);
        end

        % Value changed function: xpositionEditField_2
        function xpositionEditField_2ValueChanged(app, event)
            value = app.xpositionEditField_2.Value;
            app.wave2.x0 = value;
            app.refreshall(2);
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            value = app.AmplitudeEditField_2.Value;
            app.wave2.A = value;
            app.refreshall(2);
        end

        % Value changed function: WavelengthEditField_2
        function WavelengthEditField_2ValueChanged(app, event)
            value = app.WavelengthEditField_2.Value;
            app.wave2.lambda = value;
            app.refreshall(2);
        end

        % Value changed function: WavetypeDropDown_2
        function WavetypeDropDown_2ValueChanged(app, event)
            value = app.WavetypeDropDown_2.Value;
            app.wave2.type = value;
            app.refreshall(2);
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            value = app.AzimuthEditField.Value;
            app.wave1.theta = str2num(value);
            app.refreshall(1);
        end

        % Value changed function: AzimuthEditField_2
        function AzimuthEditField_2ValueChanged(app, event)
            value = app.AzimuthEditField_2.Value;
            app.wave2.theta = str2num(value);
            app.refreshall(2);
        end

        % Value changed function: AnimateButton
        function AnimateButtonValueChanged(app, event)
            value = app.AnimateButton.Value;

            if value
                app.t0 = simulate_wave(2, app.t0, linspace(-5, 5, 90),  linspace(-5, 5, 90), app.wave1+app.wave2, app.UIAxes);
                draw_waves(app.t0, linspace(-5, 5, 300), linspace(-5, 5, 300), app.wave1+app.wave2, 'real', app.UIAxes);
                app.AnimateButton.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real part')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Intensity')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Power')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AnimateButton = uibutton(app.UIFigure, 'state');
            app.AnimateButton.ValueChangedFcn = createCallbackFcn(app, @AnimateButtonValueChanged, true);
            app.AnimateButton.Text = 'Animate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Wave1Tab = uitab(app.TabGroup);
            app.Wave1Tab.Title = 'Wave 1';
            app.WavetypeDropDown_3Label = uilabel(app.Wave1Tab);
            app.WavetypeDropDown_3Label.Text = 'Wave type';
            app.WavetypeDropDown = uidropdown(app.Wave1Tab);
            app.WavetypeDropDown.Items = {'Plane wave', 'Gaussian', 'Cylindrical'};
            app.WavetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WavetypeDropDownValueChanged, true);
            app.WavetypeDropDown.Value = 'Plane wave';
            app.WavelengthEditField_3Label = uilabel(app.Wave1Tab);
            app.WavelengthEditField_3Label.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.Wave1Tab, 'numeric');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.AmplitudeEditField_3Label = uilabel(app.Wave1Tab);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Wave1Tab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.xpositionEditField_3Label = uilabel(app.Wave1Tab);
            app.xpositionEditField_3Label.Text = 'x-position';
            app.xpositionEditField = uieditfield(app.Wave1Tab, 'numeric');
            app.xpositionEditField.ValueChangedFcn = createCallbackFcn(app, @xpositionEditFieldValueChanged, true);
            app.ypositionEditField_3Label = uilabel(app.Wave1Tab);
            app.ypositionEditField_3Label.Text = 'y-position';
            app.ypositionEditField = uieditfield(app.Wave1Tab, 'numeric');
            app.ypositionEditField.ValueChangedFcn = createCallbackFcn(app, @ypositionEditFieldValueChanged, true);
            app.AzimuthEditField_3Label_2 = uilabel(app.Wave1Tab);
            app.AzimuthEditField_3Label_2.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.Wave1Tab, 'text');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.Wave2Tab = uitab(app.TabGroup);
            app.Wave2Tab.Title = 'Wave 2';
            app.WavetypeDropDown_2Label = uilabel(app.Wave2Tab);
            app.WavetypeDropDown_2Label.Text = 'Wave type';
            app.WavetypeDropDown_2 = uidropdown(app.Wave2Tab);
            app.WavetypeDropDown_2.Items = {'Plane wave', 'Gaussian', 'Cylindrical'};
            app.WavetypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WavetypeDropDown_2ValueChanged, true);
            app.WavetypeDropDown_2.Value = 'Plane wave';
            app.WavelengthEditField_2Label = uilabel(app.Wave2Tab);
            app.WavelengthEditField_2Label.Text = 'Wavelength';
            app.WavelengthEditField_2 = uieditfield(app.Wave2Tab, 'numeric');
            app.WavelengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditField_2ValueChanged, true);
            app.AmplitudeEditField_2Label = uilabel(app.Wave2Tab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.Wave2Tab, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.xpositionEditField_4Label = uilabel(app.Wave2Tab);
            app.xpositionEditField_4Label.Text = 'x-position';
            app.xpositionEditField_2 = uieditfield(app.Wave2Tab, 'numeric');
            app.xpositionEditField_2.ValueChangedFcn = createCallbackFcn(app, @xpositionEditField_2ValueChanged, true);
            app.ypositionEditField_4Label = uilabel(app.Wave2Tab);
            app.ypositionEditField_4Label.Text = 'y-position';
            app.ypositionEditField_2 = uieditfield(app.Wave2Tab, 'numeric');
            app.ypositionEditField_2.ValueChangedFcn = createCallbackFcn(app, @ypositionEditField_2ValueChanged, true);
            app.AzimuthEditField_3Label_3 = uilabel(app.Wave2Tab);
            app.AzimuthEditField_3Label_3.Text = 'Azimuth';
            app.AzimuthEditField_2 = uieditfield(app.Wave2Tab, 'text');
            app.AzimuthEditField_2.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end